classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        PayoffDropDownLabel       matlab.ui.control.Label
        PayoffDropDown            matlab.ui.control.DropDown
        EliminationDropDownLabel  matlab.ui.control.Label
        EliminationDropDown       matlab.ui.control.DropDown
        SimulationSwitchLabel     matlab.ui.control.Label
        SimulationSwitch          matlab.ui.control.ToggleSwitch
        ResetButton               matlab.ui.control.Button
        Pay2WinLabel              matlab.ui.control.Label
        StepButton                matlab.ui.control.Button
        CoinsEditFieldLabel       matlab.ui.control.Label
        CoinsEditField            matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        model = Model
    end


    methods (Access = private)

        % Callback function: EliminationDropDown, PayoffDropDown, 
        % UIFigure
        function PayoffDropDownValueChanged(app, event)
            value = app.PayoffDropDown.Value;
            app.model.setPayoff(value); % Payoff rule changed, tell game to change!
        end

        % Value changed function: EliminationDropDown
        function EliminationDropDownValueChanged(app, event)
            value = app.EliminationDropDown.Value;
            app.model.setElimination(value); % Elimination rule changed, tell game to change!
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.model.reset(); % Reset button was pressed to reset model
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            app.model.evolve(); % Step button was pressed to step the model
        end

        % Value changed function: SimulationSwitch
        function SimulationSwitchValueChanged(app, event)
            value = app.SimulationSwitch.Value;
            app.model.toggle(); % Switch was toggled to toggle the model
        end

        % Value changed function: CoinsEditField
        function CoinsEditFieldValueChanged(app, event)
            value = app.CoinsEditField.Value;
            app.model.setCoins(value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @PayoffDropDownValueChanged, true);
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Movie of grid');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Strategy population');
            xlabel(app.UIAxes2, 'time');
            ylabel(app.UIAxes2, 'population');
            app.PayoffDropDownLabel = uilabel(app.UIFigure);
            app.PayoffDropDownLabel.Text = 'Payoff';
            app.PayoffDropDown = uidropdown(app.UIFigure);
            app.PayoffDropDown.Items = {'Simple', 'Linear', 'Non-linear'};
            app.PayoffDropDown.ValueChangedFcn = createCallbackFcn(app, @PayoffDropDownValueChanged, true);
            app.PayoffDropDown.Value = 'Simple';
            app.EliminationDropDownLabel = uilabel(app.UIFigure);
            app.EliminationDropDownLabel.Text = 'Elimination';
            app.EliminationDropDown = uidropdown(app.UIFigure);
            app.EliminationDropDown.Items = {'Losers', 'Unweighted random', 'Weighted random'};
            app.EliminationDropDown.ValueChangedFcn = createCallbackFcn(app, @EliminationDropDownValueChanged, true);
            app.EliminationDropDown.Value = 'Losers';
            app.SimulationSwitchLabel = uilabel(app.UIFigure);
            app.SimulationSwitchLabel.Text = 'Simulation';
            app.SimulationSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @SimulationSwitchValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Pay2WinLabel = uilabel(app.UIFigure);
            app.Pay2WinLabel.Text = 'Pay2Win';
            app.StepButton = uibutton(app.UIFigure, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.CoinsEditFieldLabel = uilabel(app.UIFigure);
            app.CoinsEditFieldLabel.Text = 'Coins';
            app.CoinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoinsEditField.ValueChangedFcn = createCallbackFcn(app, @CoinsEditFieldValueChanged, true);
            app.CoinsEditField.Limits = [1 Inf];
            app.CoinsEditField.Value = 9;
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end